classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CancelButton            matlab.ui.control.Button
        OKButton                matlab.ui.control.Button
        password                matlab.ui.control.EditField
        username                matlab.ui.control.EditField
        EnteryourpasswordLabel  matlab.ui.control.Label
        EnteryourusernameLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if(app.username.Value == "z")
                if(app.password.Value == "z")
                    app.delete();
                    sineWave = SineWave;

                    A = 1;
                    f = 1;
                    w = 2*pi*f;
                    t = 0:0.016:10;
                    y1 = A * sin(w*t);
                    plot(sineWave.contin, y1);

                    y2 = y1;
                    y2(y2<0) = 0;
                    plot(sineWave.discontin, y2);
                        
                    y3 = y1;
                    y3(y3 < 0) = abs(y3(y3 < 0));
                    plot(sineWave.dc, y3);
                else
                    msgbox("Wrong password!");
                    app.password.Value = "";
                end
            else
                msgbox("Username not available!");
                app.username.Value = "";
                app.password.Value = "";
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnteryourusernameLabel = uilabel(app.UIFigure);
            app.EnteryourusernameLabel.Text = 'Enter your user name :';
            app.EnteryourpasswordLabel = uilabel(app.UIFigure);
            app.EnteryourpasswordLabel.Text = 'Enter your password :';
            app.username = uieditfield(app.UIFigure, 'text');
            app.password = uieditfield(app.UIFigure, 'text');
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end